classdef VideoVibrometry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VideoSelectionTab               matlab.ui.container.Tab
        BrowseForVideoButton            matlab.ui.control.Button
        BeginSliderLabel                matlab.ui.control.Label
        BeginSlider                     matlab.ui.control.Slider
        BeginTimeEditFieldLabel         matlab.ui.control.Label
        BeginTimeEditField              matlab.ui.control.NumericEditField
        StartNextFrameButton            matlab.ui.control.Button
        StartPrevFrameButton            matlab.ui.control.Button
        EndPrevFrameButton              matlab.ui.control.Button
        EndNextFrameButton              matlab.ui.control.Button
        EndTimeEditFieldLabel           matlab.ui.control.Label
        EndTimeEditField                matlab.ui.control.NumericEditField
        EndSliderLabel                  matlab.ui.control.Label
        EndSlider                       matlab.ui.control.Slider
        ClearMaskButton                 matlab.ui.control.Button
        SetCropButton                   matlab.ui.control.Button
        ClearCropButton                 matlab.ui.control.Button
        ShowAllVideoCheckBox            matlab.ui.control.CheckBox
        SetMaskButton                   matlab.ui.control.Button
        VideoTitleTextAreaLabel         matlab.ui.control.Label
        VideoTitleTextArea              matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
        ProcessingTab                   matlab.ui.container.Tab
        AddDeletePointsButton           matlab.ui.control.Button
        ApplyRectangularGridButton      matlab.ui.control.Button
        ApplyPolarGridButton            matlab.ui.control.Button
        SampleRegionWidthLabel          matlab.ui.control.Label
        SampleRegionWidthSpinner        matlab.ui.control.Spinner
        HorizontalPixelSpacingSpinnerLabel  matlab.ui.control.Label
        HorizontalPixelSpacingSpinner   matlab.ui.control.Spinner
        VerticalPixelSpacingSpinnerLabel  matlab.ui.control.Label
        VerticalPixelSpacingSpinner     matlab.ui.control.Spinner
        HorizontalSamplesSpinnerLabel   matlab.ui.control.Label
        HorizontalSamplesSpinner        matlab.ui.control.Spinner
        VerticalSamplesSpinnerLabel     matlab.ui.control.Label
        VerticalSamplesSpinner          matlab.ui.control.Spinner
        RadialPixelSpacingSpinnerLabel  matlab.ui.control.Label
        RadialPixelSpacingSpinner       matlab.ui.control.Spinner
        StartAngleSpinnerLabel          matlab.ui.control.Label
        StartAngleSpinner               matlab.ui.control.Spinner
        RadialSamplesSpinnerLabel       matlab.ui.control.Label
        RadialSamplesSpinner            matlab.ui.control.Spinner
        AngularSamplesSpinnerLabel      matlab.ui.control.Label
        AngularSamplesSpinner           matlab.ui.control.Spinner
        ClearAllButton                  matlab.ui.control.Button
        UseEntireImageCheckBox          matlab.ui.control.CheckBox
        NumberofprocessingscalesSpinnerLabel  matlab.ui.control.Label
        NumberofprocessingscalesSpinner  matlab.ui.control.Spinner
        BasescaleEditFieldLabel         matlab.ui.control.Label
        BasescaleEditField              matlab.ui.control.NumericEditField
        UseMaxScalesButton              matlab.ui.control.Button
        ProcessVideoButton              matlab.ui.control.Button
        ProcessingProgressGaugeLabel    matlab.ui.control.Label
        ProcessingProgressGauge         matlab.ui.control.SemicircularGauge
        UIAxes2                         matlab.ui.control.UIAxes
        PostProcessingTab               matlab.ui.container.Tab
        ClearBoxesButton                matlab.ui.control.Button
        UseAllBoxesButton               matlab.ui.control.Button
        SelectBoxesButton               matlab.ui.control.Button
        ShowAverageVelocitiesButton     matlab.ui.control.StateButton
        ShowAveragePositionsButton      matlab.ui.control.StateButton
        ShowonlyselectedboxesCheckBox   matlab.ui.control.CheckBox
        GeneratePlotsButton             matlab.ui.control.Button
        SaveProcessedDataButton         matlab.ui.control.Button
        ShowVelocityFrequenciesButton   matlab.ui.control.StateButton
        ShowPositionFrequenciesButton   matlab.ui.control.StateButton
        SGWindowEditFieldLabel          matlab.ui.control.Label
        SGWindowEditField               matlab.ui.control.NumericEditField
        ShowSpectrogramsButton          matlab.ui.control.StateButton
        SGVBucketsEditFieldLabel        matlab.ui.control.Label
        SGVBucketsEditField             matlab.ui.control.NumericEditField
        LoadProcessedDataButton         matlab.ui.control.Button
        FrameRateEditFieldLabel         matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
        HighpassFilterEditFieldLabel    matlab.ui.control.Label
        HighpassFilterEditField         matlab.ui.control.NumericEditField
        LowpassFilterEditFieldLabel     matlab.ui.control.Label
        LowpassFilterEditField          matlab.ui.control.NumericEditField
        UIAxes3                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        file % Holds the file location of the selected file
        vidReader % Holds the video reader object of the video to be analyzed
        lastFrame % Last frame in video
        startFrame % Beginning frame of analysis
        frameRate % Actual framerate at which to analyze video
        baseScale % Number from 0 to 1, shows how much video is scaled down.  0.25 is 25% of width/height.  Lower means faster
        numScales % Number of spacial scales at which to process.  Each captures vibration info at different scales
        endFrame % Ending frame of analysis
        currentFrame % Frame of video to display in UIAxes
        showAll % Boolean indicating whether or not to just show cropped video region
        crop % Rectangle object holding [xstart, ystart, xwidth, ywidth] of video to analyze
        mask % List of points indicating vertices of mask polygon.  Points outside of mask will be ignored after analysis
        points % List of points indicating (xcenter, ycenter) of boxes to analyze, chosen individually
        rectPoints % Same as 'points' but for boxes selected using rectangluar grid
        polPoints % Same as 'points' but for boxes selected using polar grid
        boxWidth % Width of boxes centered around chosen points.  Bigger captures more action and takes longer
        rects % List of rectangles in the frame of the original image to analyze
        ts % List of time vectors
        Vxs % List of 3D matrices, corresponding to x velocities in each frame for each sample region
        Vys % List of 3D matrices, corresponding to y velocities in each frame for each sample region
        numberAssignment % Array corresponding to points listing what order to plot analyzed regions
        lastFrameTime % Float holding time between video being loaded and last video frame update
    end

    methods (Access = private)
    
        function setTimes(app,startF,endF,priority)
            if strcmp(priority,'start')
                if startF > endF
                    endF = startF;
                end
                if startF > app.lastFrame
                    startF = app.lastFrame;
                    endF = startF;
                end
                if startF < 1
                    startF = 1;
                end
                app.currentFrame = startF;
            elseif strcmp(priority,'end')
                if endF < startF
                    startF = endF;
                end
                if endF > app.lastFrame
                    endF = app.lastFrame;
                end
                if endF < 1
                    endF = 1;
                    startF = 1;
                end
                app.currentFrame = endF;
            end
            app.startFrame = startF;
            app.endFrame = endF;
            
            GUIframerate = 10;
            if toc - app.lastFrameTime > 1/GUIframerate
                app.dispIm();
                app.lastFrameTime = toc;
            end

            startT = (startF - 1)/app.vidReader.FrameRate;
            if startT < 0
                startT = 0;
            elseif startT > app.vidReader.Duration
                startT = app.vidReader.Duration;
            end
            app.BeginTimeEditField.Value = startT;
            app.BeginSlider.Value = startT;
            endT = (endF - 1)/app.vidReader.FrameRate;
            if endT < 0
                endT = 0;
            elseif endT > app.vidReader.Duration
                endT = app.vidReader.Duration;
            end
            app.EndTimeEditField.Value = endT;
            app.EndSlider.Value = endT;
        end
    
        function dispIm(app)
            im = app.vidReader.read(app.currentFrame);
            if app.crop
                if app.showAll
                    imshow(im,'Parent',app.UIAxes);
                    xm = app.crop(1);
                    ym = app.crop(2);
                    width = app.crop(3);
                    height = app.crop(4);
                    line(app.UIAxes,[xm xm+width],[ym ym],'Color','r');
                    line(app.UIAxes,[xm xm+width],[ym+height ym+height],'Color','r');
                    line(app.UIAxes,[xm xm],[ym ym+height],'Color','r');
                    line(app.UIAxes,[xm+width xm+width],[ym ym+height],'Color','r');
                else
                    im = imcrop(im,app.crop);
                    imshow(im,'Parent',app.UIAxes);
                end
            else
                imshow(im,'Parent',app.UIAxes);
            end
            if app.mask
                numPoints = size(app.mask,1);
                thisMask = app.mask;
                if app.crop & app.showAll
                    thisMask(:,1) = thisMask(:,1) + app.crop(1);
                    thisMask(:,2) = thisMask(:,2) + app.crop(2);
                end
                for i = 1:numPoints
                    if i == numPoints
                        line(app.UIAxes,[thisMask(numPoints,1),thisMask(1,1)],[thisMask(numPoints,2),thisMask(1,2)],'Color','m');
                    else
                        line(app.UIAxes,[thisMask(i,1),thisMask(i+1,1)],[thisMask(i,2),thisMask(i+1,2)],'Color','m');
                    end
                end
            end
        end
    
        function [xmin,xmax,ymin,ymax] = correctBox(app,xmin,xmax,ymin,ymax)
            if xmin < 1
                xmin = 1;
            end
            if ymin < 1
                ymin = 1;
            end
            width = app.vidReader.Width;
            height = app.vidReader.Height;
            if app.crop
                width = app.crop(3);
                height = app.crop(4);
            end
            if xmax > width;
                xmax = width;
            end
            if ymax > height;
                ymax = height;
            end
        end
    
        function dispIm2(app)
            im = app.vidReader.read(app.currentFrame);
            if app.crop
                im = imcrop(im,app.crop);
            end
            imshow(im,'Parent',app.UIAxes2);

            if app.mask
                numPoints = size(app.mask,1);
                thisMask = app.mask;
                for i = 1:numPoints
                    if i == numPoints
                        line(app.UIAxes2,[thisMask(numPoints,1),thisMask(1,1)],[thisMask(numPoints,2),thisMask(1,2)],'Color','m');
                    else
                        line(app.UIAxes2,[thisMask(i,1),thisMask(i+1,1)],[thisMask(i,2),thisMask(i+1,2)],'Color','m');
                    end
                end
            end
            
            points = [app.points;app.rectPoints;app.polPoints];
            for point = 1:size(points,1)
                x = points(point,1);
                y = points(point,2);
                width = floor(app.boxWidth/2);
                [xmin,xmax,ymin,ymax] = app.correctBox(x-width,x+width,y-width,y+width);
                line(app.UIAxes2,[xmin,xmax],[ymin,ymin],'Color','r','LineWidth',1.5);
                line(app.UIAxes2,[xmin,xmax],[ymax,ymax],'Color','r','LineWidth',1.5);
                line(app.UIAxes2,[xmin,xmin],[ymin,ymax],'Color','r','LineWidth',1.5);
                line(app.UIAxes2,[xmax,xmax],[ymin,ymax],'Color','r','LineWidth',1.5);
            end
        end
    
        function dispIm3(app)
            onlySelected = app.ShowonlyselectedboxesCheckBox.Value;
            im = app.vidReader.read(app.currentFrame);
            if app.crop
                im = imcrop(im,app.crop);
            end
            imshow(im,'Parent',app.UIAxes3);
            
            points = [app.points;app.rectPoints;app.polPoints];
            if onlySelected
                for point = 1:size(points,1)
                    if app.numberAssignment(point)
                        x = points(point,1);
                        y = points(point,2);
                        width = floor(app.boxWidth/2);
                        [xmin,xmax,ymin,ymax] = app.correctBox(x-width,x+width,y-width,y+width);
                        line(app.UIAxes3,[xmin,xmax],[ymin,ymin],'Color','r','LineWidth',1.5);
                        line(app.UIAxes3,[xmin,xmax],[ymax,ymax],'Color','r','LineWidth',1.5);
                        line(app.UIAxes3,[xmin,xmin],[ymin,ymax],'Color','r','LineWidth',1.5);
                        line(app.UIAxes3,[xmax,xmax],[ymin,ymax],'Color','r','LineWidth',1.5);
                    end
                end
            else
                for point = 1:size(points,1)
                    x = points(point,1);
                    y = points(point,2);
                    width = floor(app.boxWidth/2);
                    [xmin,xmax,ymin,ymax] = app.correctBox(x-width,x+width,y-width,y+width);
                    line(app.UIAxes3,[xmin,xmax],[ymin,ymin],'Color','r','LineWidth',1.5);
                    line(app.UIAxes3,[xmin,xmax],[ymax,ymax],'Color','r','LineWidth',1.5);
                    line(app.UIAxes3,[xmin,xmin],[ymin,ymax],'Color','r','LineWidth',1.5);
                    line(app.UIAxes3,[xmax,xmax],[ymin,ymax],'Color','r','LineWidth',1.5);
                end
            end

            for number = 1:numel(app.numberAssignment)
                if app.numberAssignment(number) > 0
                    position = points(number,:);
                    text(app.UIAxes3,position(1),position(2),num2str(app.numberAssignment(number)),...
                    'Color','red','HorizontalAlignment','center','FontSize',18,'FontWeight','bold');
                end
            end
        end
    
        function [x, y] = correctPoint(app,x,y)
            x = floor(x);
            y = floor(y);
            if x < 1
                x = 1;
            end
            if x > app.vidReader.Width
                x = app.vidReader.Width;
            end
            if y < 1
                y = 1;
            end
            if y > app.vidReader.Height
                y = app.vidReader.Height;
            end
            
        end
    
        function points = findRectPoints(app,x,y)
           
            points = [];
            hp = app.HorizontalPixelSpacingSpinner.Value;
            hs = app.HorizontalSamplesSpinner.Value;
            vp = app.VerticalPixelSpacingSpinner.Value;
            vs = app.VerticalSamplesSpinner.Value;
            boxwidth = floor(app.boxWidth/2);
            width = app.vidReader.Width;
            height = app.vidReader.Height;
            if app.crop
                width = app.crop(3);
                height = app.crop(4);
            end
            for xn = [x:hp:x+hp*(hs-1)]
                if xn-boxwidth >= 1 && xn+boxwidth <= width
                    for yn = [y:vp:y+vp*(vs-1)]
                        if yn-boxwidth >= 1 && yn+boxwidth <= height
                            points(end+1,:) = [xn,yn];
                        end
                    end
                end
            end
            
        end
        
        function points = findPolPoints(app,x,y)
            width = app.vidReader.Width;
            height = app.vidReader.Height;
            if app.crop
                width = app.crop(3);
                height = app.crop(4);
            end
            boxwidth = floor(app.boxWidth/2);
            
            points = [];
            if x-boxwidth >= 1 && x+boxwidth <= width
                if y-boxwidth >= 1 && y+boxwidth <= height
                    points = [x,y];
                end
            end
            radialPixels = app.RadialPixelSpacingSpinner.Value;
            radialSamples = app.RadialSamplesSpinner.Value;
            startAngle = app.StartAngleSpinner.Value;
            angularSamples = app.AngularSamplesSpinner.Value;
            angleDistance = 360/angularSamples;
            boxwidth = floor(app.boxWidth/2);
            width = app.vidReader.Width;
            height = app.vidReader.Height;
            if app.crop
                width = app.crop(3);
                height = app.crop(4);
            end
            for radial = [radialPixels:radialPixels:radialPixels*(radialSamples-1)]
                for angular = [startAngle:angleDistance:startAngle+angleDistance*(angularSamples-1)]
                    xn = x + floor(radial*cos(angular*pi/180));
                    yn = y - floor(radial*sin(angular*pi/180));
                    if xn-boxwidth >= 1 && xn+boxwidth <= width
                        if yn-boxwidth >= 1 && yn+boxwidth <= height
                            points(end+1,:) = [xn,yn];
                        end
                    end
                end
            end
            
        end
    
        function rects = points2Rects(app)
           
            width = floor(app.boxWidth/2);
            points = [app.points;app.rectPoints;app.polPoints];
            rects = [];
            for i = 1:size(points,1)
                x = points(i,1);
                y = points(i,2);
                [xmin,xmax,ymin,ymax] = app.correctBox(x-width,x+width,y-width,y+width);
                if app.crop
                    xmin = xmin + app.crop(1);
                    xmax = xmax + app.crop(1);
                    ymin = ymin + app.crop(2);
                    ymax = ymax + app.crop(2);
                end
                rects = [rects;[xmin,ymin,xmax-xmin,ymax-ymin]];
            end
            app.rects = rects;
                
        end
    
        function enableAll(app)
            app.BeginSlider.Enable = 'on';
            app.BeginTimeEditField.Enable = 'on';
            app.StartNextFrameButton.Enable = 'on';
            app.StartPrevFrameButton.Enable = 'on';
            app.EndPrevFrameButton.Enable = 'on';
            app.EndNextFrameButton.Enable = 'on';
            app.EndTimeEditField.Enable = 'on';
            app.EndSlider.Enable = 'on';
            app.ClearMaskButton.Enable = 'on';
            app.SetCropButton.Enable = 'on';
            app.ClearCropButton.Enable = 'on';
            app.ShowAllVideoCheckBox.Enable = 'on';
            app.SetMaskButton.Enable = 'on';
            app.UseEntireImageCheckBox.Enable = 'on';
            app.AddDeletePointsButton.Enable = 'on';
            app.ClearAllButton.Enable = 'on';
            app.SampleRegionWidthSpinner.Enable = 'on';
            app.ApplyRectangularGridButton.Enable = 'on';
            app.HorizontalPixelSpacingSpinner.Enable = 'on';
            app.VerticalPixelSpacingSpinner.Enable = 'on';
            app.HorizontalSamplesSpinner.Enable = 'on';
            app.VerticalSamplesSpinner.Enable = 'on';
            app.ApplyPolarGridButton.Enable = 'on';
            app.RadialPixelSpacingSpinner.Enable = 'on';
            app.StartAngleSpinner.Enable = 'on';
            app.RadialSamplesSpinner.Enable = 'on';
            app.AngularSamplesSpinner.Enable = 'on';
            app.BasescaleEditField.Enable = 'on';
            app.NumberofprocessingscalesSpinner.Enable = 'on';
            app.UseMaxScalesButton.Enable = 'on';
            app.ProcessVideoButton.Enable = 'on';
            app.ProcessingProgressGauge.Enable = 'on';
            app.ProcessingProgressGaugeLabel.Enable = 'on';
            app.SelectBoxesButton.Enable = 'on';
            app.UseAllBoxesButton.Enable = 'on';
            app.ClearBoxesButton.Enable = 'on';
            app.ShowonlyselectedboxesCheckBox.Enable = 'on';
            app.ShowAverageVelocitiesButton.Enable = 'on';
            app.ShowAveragePositionsButton.Enable = 'on';
            app.ShowVelocityFrequenciesButton.Enable = 'on';
            app.ShowPositionFrequenciesButton.Enable = 'on';
            app.SaveProcessedDataButton.Enable = 'on';
            app.SGWindowEditField.Enable = 'on';
            app.SGVBucketsEditField.Enable = 'on';
            app.SGWindowEditFieldLabel.Enable = 'on';
            app.SGVBucketsEditFieldLabel.Enable = 'on';
            app.ShowSpectrogramsButton.Enable = 'on';
            app.GeneratePlotsButton.Enable = 'on';
            app.FrameRateEditField.Enable = 'on';
            app.HighpassFilterEditField.Enable = 'on';
            app.LowpassFilterEditField.Enable = 'on';
            app.FrameRateEditFieldLabel.Enable = 'on';
            app.HighpassFilterEditFieldLabel.Enable = 'on';
            app.LowpassFilterEditFieldLabel.Enable = 'on';
            app.VideoTitleTextArea.Enable = 'on';
            app.VideoTitleTextAreaLabel.Enable = 'on';
        end
    
        function disableAll(app)
            app.BeginSlider.Enable = 'off';
            app.BeginTimeEditField.Enable = 'off';
            app.StartNextFrameButton.Enable = 'off';
            app.StartPrevFrameButton.Enable = 'off';
            app.EndPrevFrameButton.Enable = 'off';
            app.EndNextFrameButton.Enable = 'off';
            app.EndTimeEditField.Enable = 'off';
            app.EndSlider.Enable = 'off';
            app.ClearMaskButton.Enable = 'off';
            app.SetCropButton.Enable = 'off';
            app.ClearCropButton.Enable = 'off';
            app.ShowAllVideoCheckBox.Enable = 'off';
            app.SetMaskButton.Enable = 'off';
            app.UseEntireImageCheckBox.Enable = 'off';
            app.AddDeletePointsButton.Enable = 'off';
            app.ClearAllButton.Enable = 'off';
            app.SampleRegionWidthSpinner.Enable = 'off';
            app.ApplyRectangularGridButton.Enable = 'off';
            app.HorizontalPixelSpacingSpinner.Enable = 'off';
            app.VerticalPixelSpacingSpinner.Enable = 'off';
            app.HorizontalSamplesSpinner.Enable = 'off';
            app.VerticalSamplesSpinner.Enable = 'off';
            app.ApplyPolarGridButton.Enable = 'off';
            app.RadialPixelSpacingSpinner.Enable = 'off';
            app.StartAngleSpinner.Enable = 'off';
            app.RadialSamplesSpinner.Enable = 'off';
            app.AngularSamplesSpinner.Enable = 'off';
            app.BasescaleEditField.Enable = 'off';
            app.NumberofprocessingscalesSpinner.Enable = 'off';
            app.UseMaxScalesButton.Enable = 'off';
            app.ProcessVideoButton.Enable = 'off';
            app.ProcessingProgressGauge.Enable = 'off';
            app.ProcessingProgressGaugeLabel.Enable = 'off';
            app.SelectBoxesButton.Enable = 'off';
            app.UseAllBoxesButton.Enable = 'off';
            app.ClearBoxesButton.Enable = 'off';
            app.ShowonlyselectedboxesCheckBox.Enable = 'off';
            app.ShowAverageVelocitiesButton.Enable = 'off';
            app.ShowAveragePositionsButton.Enable = 'off';
            app.ShowVelocityFrequenciesButton.Enable = 'off';
            app.ShowPositionFrequenciesButton.Enable = 'off';
            app.SaveProcessedDataButton.Enable = 'off';
            app.SGWindowEditField.Enable = 'off';
            app.SGVBucketsEditField.Enable = 'off';
            app.SGWindowEditFieldLabel.Enable = 'off';
            app.SGVBucketsEditFieldLabel.Enable = 'off';
            app.ShowSpectrogramsButton.Enable = 'off';
            app.GeneratePlotsButton.Enable = 'off';
            app.FrameRateEditField.Enable = 'off';
            app.HighpassFilterEditField.Enable = 'off';
            app.LowpassFilterEditField.Enable = 'off';
            app.FrameRateEditFieldLabel.Enable = 'off';
            app.HighpassFilterEditFieldLabel.Enable = 'off';
            app.LowpassFilterEditFieldLabel.Enable = 'off';
        end
    
        function setDefaults(app)
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes2);
            disableDefaultInteractivity(app.UIAxes3);
            app.SampleRegionWidthSpinner.Value = app.boxWidth;
            app.HorizontalPixelSpacingSpinner.Value = app.boxWidth;
            app.VerticalPixelSpacingSpinner.Value = app.boxWidth;
            app.HorizontalSamplesSpinner.Value = 4;
            app.VerticalSamplesSpinner.Value = 4;
            app.RadialPixelSpacingSpinner.Value = app.boxWidth;
            app.StartAngleSpinner.Value = 0;
            app.RadialSamplesSpinner.Value = 4;
            app.AngularSamplesSpinner.Value = 6;
            app.BasescaleEditField.Value = 1;
            app.NumberofprocessingscalesSpinner.Value = 1;
            app.SGWindowEditField.Value = 12;
            app.SGVBucketsEditField.Value = 256;
            app.baseScale = 1;
            app.numScales = 1;
            app.numberAssignment = [];
            app.ShowAveragePositionsButton.Value = 1;
            app.HighpassFilterEditField.Value = .01;
            app.LowpassFilterEditField.Value = .99;
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: BeginSlider
        function BeginSliderValueChanging(app, event)
            frame = floor(event.Value*app.vidReader.FrameRate) + 1;
            app.setTimes(frame,app.endFrame,'start');
        end

        % Button pushed function: BrowseForVideoButton
        function BrowseForVideoButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            if file
                tic
                addpath(path);
                app.VideoTitleTextArea.Value = file;
                file = fullfile(path,file);
                app.file = file;
                app.vidReader = VideoReader(file);
                im = app.vidReader.read(1);
                imshow(im,'Parent',app.UIAxes);
                app.lastFrame = app.vidReader.NumberofFrames;
                app.startFrame = 1;
                app.currentFrame = 1;
                app.endFrame = app.lastFrame;
                app.lastFrameTime = toc;
                app.BeginSlider.Limits = [0,app.vidReader.Duration];
                app.BeginSlider.MajorTicks = [0,app.vidReader.Duration/2,app.vidReader.Duration];
                app.BeginSlider.MinorTicks = [0:app.vidReader.Duration/10:app.vidReader.Duration];
                app.EndSlider.Limits = [0,app.vidReader.Duration];
                app.EndSlider.MajorTicks = [0,app.vidReader.Duration/2,app.vidReader.Duration];
                app.EndSlider.MinorTicks = [0:app.vidReader.Duration/10:app.vidReader.Duration];
                app.EndSlider.Value = app.vidReader.Duration;
                app.EndTimeEditField.Value = app.EndSlider.Value;
                app.BeginSlider.Value = 0;
                app.BeginTimeEditField.Value = 0;
                app.crop = 0;
                app.mask = 0;
                app.showAll = 0;
                app.boxWidth = floor(min(app.vidReader.Height,app.vidReader.Width)/10);
                %app.frameRate = 1000;
                %app.FrameRateEditField.Value = 1000;
                app.frameRate = round(app.vidReader.FrameRate);
                app.FrameRateEditField.Value = round(app.vidReader.FrameRate);
                app.ts = 0;
                app.Vxs = 0;
                app.Vys = 0;
                app.points = [];
                app.rectPoints = [];
                app.polPoints = [];
                app.setDefaults();
                app.enableAll();
            end
        end

        % Value changed function: BeginTimeEditField
        function BeginTimeEditFieldValueChanged(app, event)
            frame = floor(event.Value*app.vidReader.FrameRate) + 1;
            app.setTimes(frame,app.endFrame,'start');
        end

        % Button pushed function: StartNextFrameButton
        function StartNextFrameButtonPushed(app, event)
            frame = app.startFrame + 1;
            app.setTimes(frame,app.endFrame,'start');
        end

        % Button pushed function: StartPrevFrameButton
        function StartPrevFrameButtonPushed(app, event)
            frame = app.startFrame - 1;
            app.setTimes(frame,app.endFrame,'start');
        end

        % Button pushed function: EndPrevFrameButton
        function EndPrevFrameButtonPushed(app, event)
            frame = app.endFrame - 1;
            app.setTimes(app.startFrame,frame,'end');
        end

        % Button pushed function: EndNextFrameButton
        function EndNextFrameButtonPushed(app, event)
            frame = app.endFrame + 1;
            app.setTimes(app.startFrame,frame,'end');
        end

        % Value changed function: EndTimeEditField
        function EndTimeEditFieldValueChanged(app, event)
            frame = floor(event.Value*app.vidReader.FrameRate) + 1;
            app.setTimes(app.startFrame,frame,'end');
        end

        % Value changing function: EndSlider
        function EndSliderValueChanging(app, event)
            frame = floor(event.Value*app.vidReader.FrameRate) + 1;
            app.setTimes(app.startFrame,frame,'end');
        end

        % Button pushed function: SetCropButton
        function SetCropButtonPushed(app, event)
            if app.mask
                if app.crop
                    app.mask(:,1) = app.mask(:,1) + app.crop(1);
                    app.mask(:,2) = app.mask(:,2) + app.crop(2);
                end
            end
            figure
            clf
            im = im2single(squeeze(mean(app.vidReader.read(app.currentFrame),3)));
            colormap(gray);
            imagesc(im);
            if app.mask
                numPoints = size(app.mask,1);
                thisMask = app.mask;
                for i = 1:numPoints
                    if i == numPoints
                        line(gca,[thisMask(numPoints,1),thisMask(1,1)],[thisMask(numPoints,2),thisMask(1,2)],'Color','m');
                    else
                        line(gca,[thisMask(i,1),thisMask(i+1,1)],[thisMask(i,2),thisMask(i+1,2)],'Color','m');
                    end
                end
            end
            title('Click and drag to set crop, then double click in region')
            h = imrect;
            app.crop = wait(h);
            close
            if app.crop
                imsize = size(im);
                if app.crop(1) < 1
                    app.crop(3) = app.crop(3) + app.crop(1);
                    app.crop(1) = 1;
                end
                if app.crop(2) < 1
                    app.crop(4) = app.crop(4) + app.crop(2);
                    app.crop(2) = 1;
                end
                if app.crop(1) + app.crop(3) > imsize(2)
                    if app.crop(1) > imsize(2)
                        app.crop = 0;
                        app.dispIm();
                        return
                    else
                        app.crop(3) = imsize(2) - app.crop(1);
                    end
                end
                if app.crop(2) + app.crop(4) > imsize(1)
                    if app.crop(2) > imsize(1)
                        app.crop = 0;
                        app.dispIm();
                        return
                    else
                        app.crop(4) = imsize(1) - app.crop(2);
                    end
                end
                if app.mask
                    numPoints = size(app.mask,1);
                    for i = 1:numPoints
                        if app.mask(i,1) < app.crop(1)
                            app.mask = 0;
                            break;
                        end
                        if app.mask(i,1) > app.crop(1) + app.crop(3)
                            app.mask = 0;
                            break;
                        end
                        if app.mask(i,2) < app.crop(2)
                            app.mask = 0;
                            break;
                        end
                        if app.mask(i,2) > app.crop(2) + app.crop(4)
                            app.mask = 0;
                            break;
                        end
                    end
                    if app.mask
                        app.mask(:,1) = app.mask(:,1) - app.crop(1);
                        app.mask(:,2) = app.mask(:,2) - app.crop(2);
                    end
                end
            end
            app.dispIm();
        end

        % Value changed function: ShowAllVideoCheckBox
        function ShowAllVideoCheckBoxValueChanged(app, event)
            value = app.ShowAllVideoCheckBox.Value;
            app.showAll = value;
            app.dispIm();
        end

        % Button pushed function: ClearCropButton
        function ClearCropButtonPushed(app, event)
            if app.mask
                app.mask(:,1) = app.mask(:,1) + app.crop(1);
                app.mask(:,2) = app.mask(:,2) + app.crop(2);
            end
            app.crop = 0;
            app.dispIm();
        end

        % Button pushed function: ClearMaskButton
        function ClearMaskButtonPushed(app, event)
            app.mask = 0;
            app.dispIm();
        end

        % Button pushed function: SetMaskButton
        function SetMaskButtonPushed(app, event)
            figure
            clf
            im = im2single(squeeze(mean(app.vidReader.read(app.currentFrame),3)));
            colormap(gray);
            if app.crop
                im = imcrop(im,app.crop);
            end
            imagesc(im);
            title('Select points of polygon mask, then double-click in region');
            h = impoly;
            app.mask = wait(h);
            imsize = size(im);
            numPoints = size(app.mask,1);
            for i = 1:numPoints
                if app.mask(i,1) < 1
                    app.mask(i,1) = 1;
                end
                if app.mask(i,2) < 1
                    app.mask(i,2) = 1;
                end
                if app.mask(i,1) > imsize(2)
                    app.mask(i,1) = imsize(2);
                end
                if app.mask(i,2) > imsize(1)
                    app.mask(i,2) = imsize(1);
                end
            end
            close
            app.dispIm();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.UIFigureSizeChanged();
            if app.startFrame
                if strcmp(selectedTab.Title,'Processing')
                    app.dispIm2();
                end
                if strcmp(selectedTab.Title,'Post-Processing')
                    app.dispIm3();
                end
            end
        end

        % Button pushed function: AddDeletePointsButton
        function AddDeletePointsButtonPushed(app, event)
            figure
            clf
            im = im2single(squeeze(mean(app.vidReader.read(app.currentFrame),3)));
            width = floor(app.boxWidth/2);
            boxwidth = app.vidReader.Width;
            boxheight = app.vidReader.Height;
            if app.crop
                boxwidth = app.crop(3);
                boxheight = app.crop(4);
            end
            colormap(gray);
            if app.crop
                im = imcrop(im,app.crop);
            end
            points = [app.polPoints;app.rectPoints;app.points];
            pointSelected = 1;
            while(pointSelected)
                imagesc(im);
                if app.mask
                    numPoints = size(app.mask,1);
                    thisMask = app.mask;
                    for i = 1:numPoints
                        if i == numPoints
                            line([thisMask(numPoints,1),thisMask(1,1)],[thisMask(numPoints,2),thisMask(1,2)],'Color','m');
                        else
                            line([thisMask(i,1),thisMask(i+1,1)],[thisMask(i,2),thisMask(i+1,2)],'Color','m');
                        end
                    end
                end
                for point = 1:size(points,1)
                    x = points(point,1);
                    y = points(point,2);
                    [xmin,xmax,ymin,ymax] = app.correctBox(x-width,x+width,y-width,y+width);
                    line([xmin,xmax],[ymin,ymin],'Color','r');
                    line([xmin,xmax],[ymax,ymax],'Color','r');
                    line([xmin,xmin],[ymin,ymax],'Color','r');
                    line([xmax,xmax],[ymin,ymax],'Color','r');
                end
                title({'Select sample regions by midpoint, delete regions by clicking inside them';...
                    'Stop selecting by closing image'});
                newPoint = impoint;
                if size(newPoint,1)
                    foundMatch = 0;
                    matchInd = [];
                    point = newPoint.getPosition();
                    xn = point(1);
                    yn = point(2);
                    for prevPoint = 1:size(points,1)
                        xp = points(prevPoint,1);
                        yp = points(prevPoint,2);
                        if abs(xp-xn) <= width && abs(yp-yn) <= width
                            matchInd = [matchInd,prevPoint];
                            foundMatch = 1;
                        end
                    end
                    if foundMatch
                        polIndices = matchInd(matchInd <= size(app.polPoints,1));
                        polIndices = fliplr(polIndices);
                        rectIndices = matchInd(matchInd > size(app.polPoints,1) & matchInd <= (size(app.rectPoints,1)+size(app.polPoints,1)));
                        rectIndices = rectIndices - size(app.polPoints,1);
                        rectIndices = fliplr(rectIndices);
                        pointIndices = matchInd(matchInd > size(app.polPoints,1)+size(app.rectPoints,1));
                        pointIndices = pointIndices - size(app.polPoints,1) - size(app.rectPoints,1);
                        pointIndices = fliplr(pointIndices);
                        matchInd = fliplr(matchInd);
                        for i = 1:numel(matchInd)
                            points(matchInd(i),:) = [];
                        end
                        for i = 1:numel(polIndices)
                            app.polPoints(polIndices(i),:) = [];
                        end
                        for i = 1:numel(rectIndices)
                            app.rectPoints(rectIndices(i),:) = [];
                        end
                        for i = 1:numel(pointIndices)
                            app.points(pointIndices(i),:) = [];
                        end
                    else
                        position = newPoint.getPosition();
                        xp = position(1);
                        yp = position(2);
                        if xp-width >= 1 && xp+width <= boxwidth
                            if yp-width >= 1 && yp+width <= boxheight
                                points(size(points,1)+1,:) = floor(newPoint.getPosition());
                                app.points(end+1,:) = floor(newPoint.getPosition());
                            end
                        end
                    end
                else
                    pointSelected = 0;
                end
            end
            app.dispIm2();
            app.points2Rects();
        end

        % Callback function
        function SampleRegionWidthEditFieldValueChanged(app, event)
            
        end

        % Value changed function: SampleRegionWidthSpinner
        function SampleRegionWidthSpinnerValueChanged(app, event)
            value = app.SampleRegionWidthSpinner.Value;
            app.boxWidth = value;
            app.dispIm2();
        end

        % Button pushed function: ApplyRectangularGridButton
        function ApplyRectangularGridButtonPushed(app, event)
            figure
            clf
            im = im2single(squeeze(mean(app.vidReader.read(app.currentFrame),3)));
            if app.crop
                im = imcrop(im,app.crop);
            end
            colormap(gray);
            imagesc(im);
            if app.mask
                numPoints = size(app.mask,1);
                thisMask = app.mask;
                for i = 1:numPoints
                    if i == numPoints
                        line(gca,[thisMask(numPoints,1),thisMask(1,1)],[thisMask(numPoints,2),thisMask(1,2)],'Color','m');
                    else
                        line(gca,[thisMask(i,1),thisMask(i+1,1)],[thisMask(i,2),thisMask(i+1,2)],'Color','m');
                    end
                end
            end
            points = [app.points;app.polPoints];
            for point = 1:size(points,1)
                    x = points(point,1);
                    y = points(point,2);
                    width = floor(app.boxWidth/2);
                    [xmin,xmax,ymin,ymax] = app.correctBox(x-width,x+width,y-width,y+width);
                    line([xmin,xmax],[ymin,ymin],'Color','r');
                    line([xmin,xmax],[ymax,ymax],'Color','r');
                    line([xmin,xmin],[ymin,ymax],'Color','r');
                    line([xmax,xmax],[ymin,ymax],'Color','r');
                end
            title('Select top-left sample in rectangular grid')
            h = impoint;
            if size(h,1)
                position = h.getPosition();
                app.rectPoints = [];
                [x,y] = app.correctPoint(position(1),position(2));
                app.rectPoints = app.findRectPoints(x,y);
            end
            close
            app.dispIm2();
        end

        % Value changed function: HorizontalPixelSpacingSpinner
        function HorizontalPixelSpacingSpinnerValueChanged(app, event)
            value = app.HorizontalPixelSpacingSpinner.Value;
            if size(app.rectPoints,1) & value ~= 0
                app.rectPoints = app.findRectPoints(app.rectPoints(1,1),app.rectPoints(1,2));
                app.dispIm2();
            end
        end

        % Value changed function: VerticalPixelSpacingSpinner
        function VerticalPixelSpacingSpinnerValueChanged(app, event)
            value = app.VerticalPixelSpacingSpinner.Value;
            if size(app.rectPoints,1) & value ~= 0
                app.rectPoints = app.findRectPoints(app.rectPoints(1,1),app.rectPoints(1,2));
                app.dispIm2();
            end
        end

        % Value changed function: HorizontalSamplesSpinner
        function HorizontalSamplesSpinnerValueChanged(app, event)
            value = app.HorizontalSamplesSpinner.Value;
            if size(app.rectPoints,1) & value >= 1
                app.rectPoints = app.findRectPoints(app.rectPoints(1,1),app.rectPoints(1,2));
                app.dispIm2();
            elseif value < 1
                app.HorizontalSamplesSpinner.Value = 1;
                app.rectPoints = app.findRectPoints(app.rectPoints(1,1),app.rectPoints(1,2));
                app.dispIm2();
            end
        end

        % Value changed function: VerticalSamplesSpinner
        function VerticalSamplesSpinnerValueChanged(app, event)
            value = app.VerticalSamplesSpinner.Value;
            if size(app.rectPoints,1) & value >= 1
                app.rectPoints = app.findRectPoints(app.rectPoints(1,1),app.rectPoints(1,2));
                app.dispIm2();
            elseif value < 1
                app.VerticalSamplesSpinner.Value = 1;
                app.rectPoints = app.findRectPoints(app.rectPoints(1,1),app.rectPoints(1,2));
                app.dispIm2();
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.rectPoints = [];
            app.polPoints = [];
            app.points = [];
            app.dispIm2();
        end

        % Button pushed function: ApplyPolarGridButton
        function ApplyPolarGridButtonPushed(app, event)
            figure
            clf
            im = im2single(squeeze(mean(app.vidReader.read(app.currentFrame),3)));
            colormap(gray);
            if app.crop
                im = imcrop(im,app.crop);
            end
            imagesc(im);
            if app.mask
                numPoints = size(app.mask,1);
                thisMask = app.mask;
                for i = 1:numPoints
                    if i == numPoints
                        line(gca,[thisMask(numPoints,1),thisMask(1,1)],[thisMask(numPoints,2),thisMask(1,2)],'Color','m');
                    else
                        line(gca,[thisMask(i,1),thisMask(i+1,1)],[thisMask(i,2),thisMask(i+1,2)],'Color','m');
                    end
                end
            end
            points = [app.points;app.rectPoints];
            for point = 1:size(points,1)
                    x = points(point,1);
                    y = points(point,2);
                    width = floor(app.boxWidth/2);
                    [xmin,xmax,ymin,ymax] = app.correctBox(x-width,x+width,y-width,y+width);
                    line([xmin,xmax],[ymin,ymin],'Color','r');
                    line([xmin,xmax],[ymax,ymax],'Color','r');
                    line([xmin,xmin],[ymin,ymax],'Color','r');
                    line([xmax,xmax],[ymin,ymax],'Color','r');
                end
            title('Select the central sample')
            h = impoint;
            if size(h,1)
                position = h.getPosition();
                [x,y] = app.correctPoint(position(1),position(2));
                app.polPoints = app.findPolPoints(x,y);
                close
            end
            app.dispIm2();
        end

        % Value changed function: RadialPixelSpacingSpinner
        function RadialPixelSpacingSpinnerValueChanged(app, event)
            value = app.RadialPixelSpacingSpinner.Value;
            if value <= 0
                app.RadialPixelSpacingSpinner.Value = 1;
                value = 1;
            end
            if size(app.polPoints,1) && value ~= 0
                app.polPoints = app.findPolPoints(app.polPoints(1,1),app.polPoints(1,2));
                app.dispIm2();
            end
        end

        % Value changed function: StartAngleSpinner
        function StartAngleSpinnerValueChanged(app, event)
            value = app.StartAngleSpinner.Value;
            if value > 360
                app.StartAngleSpinner.Value = 360;
            elseif value < -360
                app.StartAngleSpinner.Value = -360;
            end
            if size(app.polPoints,1)
                app.polPoints = app.findPolPoints(app.polPoints(1,1),app.polPoints(1,2));
                app.dispIm2();
            end
        end

        % Value changed function: RadialSamplesSpinner
        function RadialSamplesSpinnerValueChanged(app, event)
            value = app.RadialSamplesSpinner.Value;
            if value < 1
                app.RadialSamplesSpinner.Value = 1;
            end
            if size(app.polPoints,1)
                app.polPoints = app.findPolPoints(app.polPoints(1,1),app.polPoints(1,2));
                app.dispIm2();
            end
        end

        % Value changed function: AngularSamplesSpinner
        function AngularSamplesSpinnerValueChanged(app, event)
            value = app.AngularSamplesSpinner.Value;
            if value < 1
                app.AngularSamplesSpinner.Value = 1;
            end
            if size(app.polPoints,1)
                app.polPoints = app.findPolPoints(app.polPoints(1,1),app.polPoints(1,2));
                app.dispIm2();
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            width = position(3);
            height = position(4);
            if width < 640
                width = 640;
                app.UIFigure.Position = [position(1),position(2),width,height];
            end
            if height < 480
                height = 480;
                app.UIFigure.Position = [position(1),position(2),width,height];
            end
            app.TabGroup.Position = [1,1,width,height];
            if strcmp(app.TabGroup.SelectedTab.Title,'Video Selection')
                app.BrowseForVideoButton.Position = [width-190,height-150,127,22];
                app.SetCropButton.Position = [width - 225,height-180,100,22];
                app.ClearCropButton.Position = [width - 115,height-180,100,22];
                app.ShowAllVideoCheckBox.Position = [width - 225,height-205,100,22];
                app.SetMaskButton.Position = [width - 225,height - 230,100,22];
                app.ClearMaskButton.Position = [width - 115,height - 230,100,22];
                app.StartPrevFrameButton.Position = [width - 225,185,100,22];
                app.StartNextFrameButton.Position = [width - 115,185,100,22];
                app.EndPrevFrameButton.Position = [width - 225,112,100,22];
                app.EndNextFrameButton.Position = [width - 115,112,100,22];
                app.BeginTimeEditField.Position = [width - 290,185,44,22];
                app.EndTimeEditField.Position = [width - 290,112,44,22];
                app.BeginTimeEditFieldLabel.Position = [width - 370,185,66,22];
                app.EndTimeEditFieldLabel.Position = [width - 370,112,66,22];
                app.BeginSlider.Position = [100,195,width - 490,3];
                app.EndSlider.Position = [100,121,width - 490,3];
                app.UIAxes.Position = [10,225,width - 240,height - 225 - 35];
                app.VideoTitleTextArea.Position = [width - 201,height - 115,150,22];
                app.VideoTitleTextAreaLabel.Position = [width - 157,height - 93,62,22];
            end
            if strcmp(app.TabGroup.SelectedTab.Title,'Processing')
                 app.AddDeletePointsButton.Position = [width - 223,height - 80,112,22];
                 app.ClearAllButton.Position = [width - 101,height - 80,90,22];
                 app.SampleRegionWidthSpinner.Position = [width - 126,height - 110,100,22];
                 app.SampleRegionWidthLabel.Position = [width-230,height - 114,88,28];
                 app.ApplyRectangularGridButton.Position = [width-193,height-140,140,22];
                 app.HorizontalPixelSpacingSpinner.Position = [width - 126,height - 170,100,22];
                 app.VerticalPixelSpacingSpinner.Position = [width - 126,height - 205,100,22];
                 app.HorizontalSamplesSpinner.Position = [width - 126,height - 240,100,22];
                 app.VerticalSamplesSpinner.Position = [width - 126,height - 275,100,22];
                 app.HorizontalPixelSpacingSpinnerLabel.Position = [width - 229,height-176,89,28];
                 app.VerticalPixelSpacingSpinnerLabel.Position = [width - 215,height-208,74,28];
                 app.HorizontalSamplesSpinnerLabel.Position = [width - 200,height-243,60,28];
                 app.VerticalSamplesSpinnerLabel.Position = [width-193,height-278,52,28];
                 app.ApplyPolarGridButton.Position = [width - 175,height - 305,103,22];
                 app.RadialPixelSpacingSpinner.Position = [width - 126,height - 335,100,22];
                 app.RadialPixelSpacingSpinnerLabel.Position = [width - 209,height-338,69,28];
                 app.StartAngleSpinner.Position = [width - 126,height - 370,100,22];
                 app.StartAngleSpinnerLabel.Position = [width - 205,height - 370,65,22];
                 app.RadialSamplesSpinner.Position = [width - 126,height - 405,100,22];
                 app.RadialSamplesSpinnerLabel.Position = [width - 194,height - 408,52,28];
                 app.AngularSamplesSpinner.Position = [width - 126,height - 440,100,22];
                 app.AngularSamplesSpinnerLabel.Position = [width - 194,height - 443,52,28];
                 app.UseEntireImageCheckBox.Position = [width - 167,height - 53,115,22];
                 app.BasescaleEditField.Position = [width - 335,150,100,22];
                 app.BasescaleEditFieldLabel.Position = [width - 414,150,64,22];
                 app.NumberofprocessingscalesSpinner.Position = [width - 335,110,100,22];
                 app.NumberofprocessingscalesSpinnerLabel.Position = [width-451,104,101,28];
                 app.UIAxes2.Position = [10,225,width - 240,height - 225 - 35];
                 app.UseMaxScalesButton.Position = [width - 376,72,102,22];
                 app.ProcessVideoButton.Position = [34,134,145,55];
                 app.ProcessingProgressGauge.Position = [46,57,120,65];
            end
            if strcmp(app.TabGroup.SelectedTab.Title,'Post-Processing')
                app.UIAxes3.Position = [10,225,width - 240,height - 225 - 35];
                app.SelectBoxesButton.Position = [width - 219,height - 57,209,22];
                app.UseAllBoxesButton.Position = [width - 219,height - 87,100,22];
                app.ClearBoxesButton.Position = [width - 110,height-87,100,22];
                app.ShowonlyselectedboxesCheckBox.Position = [width - 192,height-110,161,22];
                app.ShowAverageVelocitiesButton.Position = [width - 185,height - 155,155,22];
                app.ShowAveragePositionsButton.Position = [width - 185,height - 185,155,22];
                app.ShowVelocityFrequenciesButton.Position = [width - 185,height - 215,155,22];
                app.ShowPositionFrequenciesButton.Position = [width - 185,height - 245,155,22];
                app.ShowSpectrogramsButton.Position = [width - 185,height-275,155,22];
                app.GeneratePlotsButton.Position = [width - 183,54,143,54];
            end
        end

        % Value changed function: UseEntireImageCheckBox
        function UseEntireImageCheckBoxValueChanged(app, event)
            value = app.UseEntireImageCheckBox.Value;
            if value
                if app.crop(1)
                    h = app.crop(4);
                    w = app.crop(3);
                else
                    h = app.vidReader.Height;
                    w = app.vidReader.Width;
                end
                app.ClearAllButtonPushed(1)
                app.AddDeletePointsButton.Enable = 'off';
                app.ClearAllButton.Enable = 'off';
                app.SampleRegionWidthSpinner.Enable = 'off';
                app.ApplyRectangularGridButton.Enable = 'off';
                app.HorizontalPixelSpacingSpinner.Enable = 'off';
                app.VerticalPixelSpacingSpinner.Enable = 'off';
                app.HorizontalSamplesSpinner.Enable = 'off';
                app.VerticalSamplesSpinner.Enable = 'off';
                app.ApplyPolarGridButton.Enable = 'off';
                app.RadialPixelSpacingSpinner.Enable = 'off';
                app.StartAngleSpinner.Enable = 'off';
                app.RadialSamplesSpinner.Enable = 'off';
                app.AngularSamplesSpinner.Enable = 'off';
            else
                app.dispIm2();
                app.AddDeletePointsButton.Enable = 'on';
                app.ClearAllButton.Enable = 'on';
                app.SampleRegionWidthSpinner.Enable = 'on';
                app.ApplyRectangularGridButton.Enable = 'on';
                app.HorizontalPixelSpacingSpinner.Enable = 'on';
                app.VerticalPixelSpacingSpinner.Enable = 'on';
                app.HorizontalSamplesSpinner.Enable = 'on';
                app.VerticalSamplesSpinner.Enable = 'on';
                app.ApplyPolarGridButton.Enable = 'on';
                app.RadialPixelSpacingSpinner.Enable = 'on';
                app.StartAngleSpinner.Enable = 'on';
                app.RadialSamplesSpinner.Enable = 'on';
                app.AngularSamplesSpinner.Enable = 'on';
            end
        end

        % Value changed function: BasescaleEditField
        function BasescaleEditFieldValueChanged(app, event)
            value = app.BasescaleEditField.Value;
            app.baseScale = value;
            if value > 1 || value < 0
                app.baseScale = 1;
            end
            app.BasescaleEditField.Value = app.baseScale;
            if app.UseEntireImageCheckBox.Value
                maxScales = floor(log2(app.baseScale*min(app.vidReader.Width,app.vidReader.Height))) - 2;
            else
                maxScales = floor(log2(app.baseScale*app.boxWidth)) - 2;
            end
            if maxScales < 1
                maxScales = 1;
            end
            if app.numScales > maxScales
                app.numScales = maxScales;
                app.NumberofprocessingscalesSpinner.Value = maxScales;
            end
        end

        % Button pushed function: UseMaxScalesButton
        function UseMaxScalesButtonPushed(app, event)
            if app.UseEntireImageCheckBox.Value
                maxScales = floor(log2(app.baseScale*min(app.vidReader.Width,app.vidReader.Height))) - 2;
            else
                maxScales = floor(log2(app.baseScale*app.boxWidth)) - 2;
            end
            if maxScales < 1
                maxScales = 1;
            end
            app.numScales = maxScales;
            app.NumberofprocessingscalesSpinner.Value = maxScales;
        end

        % Value changed function: NumberofprocessingscalesSpinner
        function NumberofprocessingscalesSpinnerValueChanged(app, event)
            value = app.NumberofprocessingscalesSpinner.Value;
            if app.UseEntireImageCheckBox.Value
                maxScales = floor(log2(app.baseScale*min(app.vidReader.Width,app.vidReader.Height))) - 2;
            else
                maxScales = floor(log2(app.baseScale*app.boxWidth)) - 2;
            end
            if maxScales < 1
                maxScales = 1;
            end
            if value < 1
                value = 1;
                app.NumberofprocessingscalesSpinner.Value = 1;
            end
            if value > maxScales
                value = maxScales;
                app.NumberofprocessingscalesSpinner.Value = maxScales;
            end
            app.numScales = value;
        end

        % Button pushed function: ProcessVideoButton
        function ProcessVideoButtonPushed(app, event)
            vr = VideoReader(app.file);
            if app.UseEntireImageCheckBox.Value
                if app.crop
                    rectangles = app.crop;
                else
                    rectangles = [1,1,app.vidReader.Width,app.vidReader.Height];
                end
            else
                rectangles = app.points2Rects();
            end
            scale = app.baseScale;
            t0 = app.BeginTimeEditField.Value;
            nf = app.endFrame - app.startFrame;
            fs = app.frameRate;
            nscales = app.numScales;
            ts = [];
            Vxs = [];
            Vys = [];
            boxes = size(rectangles,1);
            for i = 1:size(rectangles,1)
                rect = rectangles(i,:);
                [Vxs(:,:,:,i),Vys(:,:,:,i),ts(:,i)] = phaseOpticFlow(vr,scale,rect,t0,nf,fs,nscales);
                ts(:,i) = ts(:,i) + app.BeginTimeEditField.Value;
                app.ProcessingProgressGauge.Value = int8(100*i/boxes);
            end
            app.ts = ts;
            app.Vxs = Vxs;
            app.Vys = Vys;
        end

        % Button pushed function: SelectBoxesButton
        function SelectBoxesButtonPushed(app, event)
            figure
            clf
            width = floor(app.boxWidth/2);
            boxwidth = app.vidReader.Width;
            boxheight = app.vidReader.Height;
            if app.crop
                boxwidth = app.crop(3);
                boxheight = app.crop(4);
            end
            colormap(gray);
            points = [app.polPoints;app.rectPoints;app.points];
            if numel(app.numberAssignment) == 0
                app.numberAssignment = zeros(size(points,1),1);
            end
            pointSelected = 1;
            while(pointSelected)
                im = im2single(squeeze(mean(app.vidReader.read(app.currentFrame),3)));
                if app.crop
                    im = imcrop(im,app.crop);
                end
                imagesc(im);
                for number = 1:numel(app.numberAssignment)
                    if app.numberAssignment(number) > 0
                        position = points(number,:);
                        text(position(1),position(2),num2str(app.numberAssignment(number)),'Color','red','HorizontalAlignment','center');
                    end
                end
                for point = 1:size(points,1)
                    x = points(point,1);
                    y = points(point,2);
                    [xmin,xmax,ymin,ymax] = app.correctBox(x-width,x+width,y-width,y+width);
                    line([xmin,xmax],[ymin,ymin],'Color','r');
                    line([xmin,xmax],[ymax,ymax],'Color','r');
                    line([xmin,xmin],[ymin,ymax],'Color','r');
                    line([xmax,xmax],[ymin,ymax],'Color','r');
                end
                title({'Choose sample regions to visualize, confirm by closing image'});
                newPoint = impoint;
                if size(newPoint,1)
                    point = newPoint.getPosition();
                    xn = point(1);
                    yn = point(2);
                    for prevPoint = 1:size(points,1)
                        xp = points(prevPoint,1);
                        yp = points(prevPoint,2);
                        if abs(xp-xn) <= width && abs(yp-yn) <= width
                            matchInd = prevPoint;
                            if app.numberAssignment(matchInd)
                                if app.numberAssignment(matchInd) == max(app.numberAssignment)
                                    app.numberAssignment(matchInd) = 0;
                                else
                                    val = app.numberAssignment(matchInd);
                                    app.numberAssignment(matchInd) = 0;
                                    for i = 1:numel(app.numberAssignment)
                                        if app.numberAssignment(i) > val
                                            app.numberAssignment(i) = app.numberAssignment(i) - 1;
                                        end
                                    end
                                end
                            else
                                app.numberAssignment(matchInd) = max(app.numberAssignment) + 1; 
                            end
                        end
                    end
                else
                    pointSelected = 0;
                end
            end
            app.dispIm3();
        end

        % Button pushed function: ClearBoxesButton
        function ClearBoxesButtonPushed(app, event)
            for i = 1:numel(app.numberAssignment)
                app.numberAssignment(i) = 0;
            end
            app.dispIm3();
        end

        % Button pushed function: UseAllBoxesButton
        function UseAllBoxesButtonPushed(app, event)
            points = [app.polPoints;app.rectPoints;app.points];
            for i = 1:size(points,1)
                app.numberAssignment(i) = i;
            end
            app.dispIm3();
        end

        % Value changed function: ShowonlyselectedboxesCheckBox
        function ShowonlyselectedboxesCheckBoxValueChanged(app, event)
            app.dispIm3();
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            if app.ShowAverageVelocitiesButton.Value
                if app.UseEntireImageCheckBox.Value
                    samples = 1;
                else
                    samples = max(app.numberAssignment);
                end
                figure
                clf
                for i = 1:samples
                    if app.UseEntireImageCheckBox.Value
                        pointNumber = 1;
                    else
                        pointNumber = find(app.numberAssignment == i);
                    end
                    subplot(samples,1,i);
                    t = app.ts(:,pointNumber);
                    vx = squeeze(mean(mean(app.Vxs(:,:,:,pointNumber))));
                    vy = squeeze(mean(mean(app.Vys(:,:,:,pointNumber))));
                    hold on;
                    plot(t,vx);
                    plot(t,vy);
                    axis tight;
                    if i == 1
                        legend('X Vel','Y Vel') ;
                    end
                    title(['Pixel Velocities for Box ',num2str(i)])
                end
            end
            if app.ShowAveragePositionsButton.Value
                if app.UseEntireImageCheckBox.Value
                    samples = 1;
                else
                    samples = max(app.numberAssignment);
                end
                figure
                clf
                [b,a] = butter(2,app.LowpassFilterEditField.Value,'low');
                [d,c] = butter(2,app.HighpassFilterEditField.Value,'high');
                fs = app.FrameRateEditField.Value;
                for i = 1:samples
                    if app.UseEntireImageCheckBox.Value
                        pointNumber = 1;
                    else
                        pointNumber = find(app.numberAssignment == i);
                    end
                    subplot(2*samples,1,2*i-1);
                    t = app.ts(:,pointNumber);
                    Dx = cumtrapz(1/fs,filter(b,a,app.Vxs(:,:,:,pointNumber),[],3),3);
                    Dy = cumtrapz(1/fs,filter(b,a,app.Vys(:,:,:,pointNumber),[],3),3);
                    Dx = filter(d,c,Dx,[],3);
                    Dy = filter(d,c,Dy,[],3);
                    Dx = squeeze(mean(mean(Dx)));
                    Dy = squeeze(mean(mean(Dy)));
                    maxD = max(max(abs(Dx)),max(abs(Dy)));
                    maxT = t(end);
                    plot(t,Dx);
                    axis([0 maxT -maxD maxD]);
                    ylabel('Displacement (px)');
                    title(['X Displacement for Box',num2str(i)]);
                    subplot(2*samples,1,2*i);
                    plot(t,Dy);
                    axis([0 maxT -maxD maxD]);
                    ylabel('Displacement (px)');
                    title(['Y Displacement for Box',num2str(i)]);
                    xlabel('Time (sec)');
                    axis tight;
                    %if i == 1
                    %    legend('X Pos','Y Pos') ;
                    %end
                    %title(['Pixel Displacements for Box ',num2str(i)])
                end
            end
            if app.ShowVelocityFrequenciesButton.Value
                if app.UseEntireImageCheckBox.Value
                    samples = 1;
                else
                    samples = max(app.numberAssignment);
                end
                figure
                clf
                [b,a] = butter(2,app.LowpassFilterEditField.Value,'low');
                [d,c] = butter(2,app.HighpassFilterEditField.Value,'high');
                fs = app.FrameRateEditField.Value;
                for i = 1:samples
                    if app.UseEntireImageCheckBox.Value
                        pointNumber = 1;
                    else
                        pointNumber = find(app.numberAssignment == i);
                    end
                    vx = filter(d,c,filter(b,a,app.Vxs(:,:,:,pointNumber),[],3),[],3);
                    vy = filter(d,c,filter(b,a,app.Vys(:,:,:,pointNumber),[],3),[],3);
                    vx = squeeze(mean(mean(vx)));
                    vy = squeeze(mean(mean(vy)));
                    subplot(samples,1,i);
                    hold on;
                    axis tight;
                    t = app.ts(:,pointNumber);
                    [pxx_x,f] = pwelch(vx,[],[],[],fs);
                    [pxx_y,f] = pwelch(vy,[],[],[],fs);
                    plot(f,10*log10(pxx_x),f,10*log10(pxx_y));
                    if i == 1
                        legend('X','Y') ;
                    end
                    title(['Velocity Frequency Powers for Box ',num2str(i)])
                end
            end
            if app.ShowPositionFrequenciesButton.Value
                if app.UseEntireImageCheckBox.Value
                    samples = 1;
                else
                    samples = max(app.numberAssignment);
                end
                figure
                clf
                [b,a] = butter(2,app.LowpassFilterEditField.Value,'low');
                [d,c] = butter(2,app.HighpassFilterEditField.Value,'high');
                fs = app.FrameRateEditField.Value;
                for i = 1:samples
                    if app.UseEntireImageCheckBox.Value
                        pointNumber = 1;
                    else
                        pointNumber = find(app.numberAssignment == i);
                    end
                    subplot(samples,1,i);
                    hold on;
                    axis tight;
                    Dx = cumtrapz(1/fs,filter(b,a,app.Vxs(:,:,:,pointNumber),[],3),3);
                    Dy = cumtrapz(1/fs,filter(b,a,app.Vys(:,:,:,pointNumber),[],3),3);
                    Dx = filter(d,c,Dx,[],3);
                    Dy = filter(d,c,Dy,[],3);
                    Dx = squeeze(mean(mean(Dx)));
                    Dy = squeeze(mean(mean(Dy)));
                    [pxx_x,f] = pwelch(Dx,[],[],[],fs);
                    [pxx_y,f] = pwelch(Dy,[],[],[],fs);
                    plot(f,10*log10(pxx_x),f,10*log10(pxx_y));
                    if i == 1
                        legend('X','Y') ;
                    end
                    title(['Position Frequency Powers for Box ',num2str(i)])
                end
            end
            if app.ShowSpectrogramsButton.Value
                if app.UseEntireImageCheckBox.Value
                    samples = 1;
                else
                    samples = max(app.numberAssignment);
                end
                [b,a] = butter(2,app.LowpassFilterEditField.Value,'low');
                [d,c] = butter(2,app.HighpassFilterEditField.Value,'high');
                fs = app.FrameRateEditField.Value;
                wndw = app.SGWindowEditField.Value;
                olap = int16(wndw/2);
                vbuck = app.SGVBucketsEditField.Value;
                for i = 1:samples
                    figure;
                    if app.UseEntireImageCheckBox.Value
                        pointNumber = 1;
                    else
                        pointNumber = find(app.numberAssignment == i);
                    end
                    vx = filter(d,c,filter(b,a,app.Vxs(:,:,:,pointNumber),[],3),[],3);
                    vy = filter(d,c,filter(b,a,app.Vys(:,:,:,pointNumber),[],3),[],3);
                    vx = squeeze(mean(mean(vx)));
                    vy = squeeze(mean(mean(vy)));
                    subplot(2,1,1);
                    hold on;
                    axis tight;
                    spectrogram(vx,wndw,olap,vbuck,'yaxis',fs);
                    title(['X Movement Spectrogram, Box ',num2str(i)]);
                    subplot(2,1,2);
                    spectrogram(vy,wndw,olap,vbuck,'yaxis',fs);
                    title(['Y Movement Spectrogram, Box ',num2str(i)]);
                    figure;
                    Dx = cumtrapz(1/fs,filter(b,a,app.Vxs(:,:,:,pointNumber),[],3),3);
                    Dy = cumtrapz(1/fs,filter(b,a,app.Vys(:,:,:,pointNumber),[],3),3);
                    Dx = filter(d,c,Dx,[],3);
                    Dy = filter(d,c,Dy,[],3);
                    Dx = squeeze(mean(mean(Dx)));
                    Dy = squeeze(mean(mean(Dy)));
                    subplot(2,1,1);
                    hold on;
                    axis tight;
                    spectrogram(Dx,wndw,olap,vbuck,'yaxis',fs);
                    title(['X Position Spectrogram, Box ',num2str(i)]);
                    subplot(2,1,2);
                    spectrogram(Dy,wndw,olap,vbuck,'yaxis',fs);
                    title(['Y Position Spectrogram, Box ',num2str(i)]);
                end
            end
        end

        % Button pushed function: SaveProcessedDataButton
        function SaveProcessedDataButtonPushed(app, event)
            ts = app.ts;
            vxs = app.Vxs;
            vys = app.Vys;
            vr = app.vidReader;
            curFrame = app.currentFrame;
            crop = app.crop;
            points = app.points;
            rpoints = app.rectPoints;
            ppoints = app.polPoints;
            fs = app.FrameRateEditField.Value;
            boxWidth = app.boxWidth;
            [file,path] = uiputfile('*.mat');
            file = fullfile(path,file);
            if file
                app.disableAll();
                save(file,'ts','vxs','vys','vr','curFrame','crop','points','rpoints','ppoints','fs','boxWidth','-v7.3');
                app.enableAll();
            end
        end

        % Button pushed function: LoadProcessedDataButton
        function LoadProcessedDataButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            app.disableAll();
            if file
                addpath(path);
                file = fullfile(path,file);
                load(file);
                app.ts = ts;
                app.Vxs = vxs;
                app.Vys = vys;
                app.vidReader = vr;
                app.currentFrame = curFrame;
                app.crop = crop;
                app.points = points;
                app.rectPoints = rpoints;
                app.polPoints = ppoints;
                app.FrameRateEditField.Value = fs;
                app.boxWidth = boxWidth;
                app.enableAll();
                app.setDefaults();
                app.dispIm3();
            end
            app.enableAll();
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            value = app.FrameRateEditField.Value;
            if value <= 0
                app.FrameRateEditField.Value = 1;
                app.frameRate = 1;
            else
                app.frameRate = value;
            end
        end

        % Value changed function: HighpassFilterEditField
        function HighpassFilterEditFieldValueChanged(app, event)
            value = app.HighpassFilterEditField.Value;
            if value < 0
                app.HighpassFilterEditField.Value = 0;
            elseif value > 1
                app.HighpassFilterEditField.Value = 1;
            end
        end

        % Value changed function: LowpassFilterEditField
        function LowpassFilterEditFieldValueChanged(app, event)
            value = app.LowpassFilterEditField.Value;
            if value < 0
                app.LowpassFilterEditField.Value = 0;
            elseif value > 1
                app.LowpassFilterEditField.Value = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.VideoSelectionTab = uitab(app.TabGroup);
            app.VideoSelectionTab.Title = 'Video Selection';
            app.BrowseForVideoButton = uibutton(app.VideoSelectionTab, 'push');
            app.BrowseForVideoButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseForVideoButtonPushed, true);
            app.BrowseForVideoButton.Text = 'Browse For Video';
            app.BeginSliderLabel = uilabel(app.VideoSelectionTab);
            app.BeginSliderLabel.Text = 'Begin';
            app.BeginSlider = uislider(app.VideoSelectionTab);
            app.BeginSlider.ValueChangingFcn = createCallbackFcn(app, @BeginSliderValueChanging, true);
            app.BeginSlider.Enable = 'off';
            app.BeginTimeEditFieldLabel = uilabel(app.VideoSelectionTab);
            app.BeginTimeEditFieldLabel.Text = 'Begin Time';
            app.BeginTimeEditField = uieditfield(app.VideoSelectionTab, 'numeric');
            app.BeginTimeEditField.ValueChangedFcn = createCallbackFcn(app, @BeginTimeEditFieldValueChanged, true);
            app.BeginTimeEditField.Enable = 'off';
            app.StartNextFrameButton = uibutton(app.VideoSelectionTab, 'push');
            app.StartNextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @StartNextFrameButtonPushed, true);
            app.StartNextFrameButton.Enable = 'off';
            app.StartNextFrameButton.Text = 'Next Frame';
            app.StartPrevFrameButton = uibutton(app.VideoSelectionTab, 'push');
            app.StartPrevFrameButton.ButtonPushedFcn = createCallbackFcn(app, @StartPrevFrameButtonPushed, true);
            app.StartPrevFrameButton.Enable = 'off';
            app.StartPrevFrameButton.Text = 'Prev Frame';
            app.EndPrevFrameButton = uibutton(app.VideoSelectionTab, 'push');
            app.EndPrevFrameButton.ButtonPushedFcn = createCallbackFcn(app, @EndPrevFrameButtonPushed, true);
            app.EndPrevFrameButton.Enable = 'off';
            app.EndPrevFrameButton.Text = 'Prev Frame';
            app.EndNextFrameButton = uibutton(app.VideoSelectionTab, 'push');
            app.EndNextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @EndNextFrameButtonPushed, true);
            app.EndNextFrameButton.Enable = 'off';
            app.EndNextFrameButton.Text = 'Next Frame';
            app.EndTimeEditFieldLabel = uilabel(app.VideoSelectionTab);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.VideoSelectionTab, 'numeric');
            app.EndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditFieldValueChanged, true);
            app.EndTimeEditField.Enable = 'off';
            app.EndSliderLabel = uilabel(app.VideoSelectionTab);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.VideoSelectionTab);
            app.EndSlider.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.EndSlider.Enable = 'off';
            app.ClearMaskButton = uibutton(app.VideoSelectionTab, 'push');
            app.ClearMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMaskButtonPushed, true);
            app.ClearMaskButton.Enable = 'off';
            app.ClearMaskButton.Text = 'Clear Mask';
            app.SetCropButton = uibutton(app.VideoSelectionTab, 'push');
            app.SetCropButton.ButtonPushedFcn = createCallbackFcn(app, @SetCropButtonPushed, true);
            app.SetCropButton.Enable = 'off';
            app.SetCropButton.Text = 'Set Crop';
            app.ClearCropButton = uibutton(app.VideoSelectionTab, 'push');
            app.ClearCropButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCropButtonPushed, true);
            app.ClearCropButton.Enable = 'off';
            app.ClearCropButton.Text = 'Clear Crop';
            app.ShowAllVideoCheckBox = uicheckbox(app.VideoSelectionTab);
            app.ShowAllVideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAllVideoCheckBoxValueChanged, true);
            app.ShowAllVideoCheckBox.Enable = 'off';
            app.ShowAllVideoCheckBox.Text = 'Show all video';
            app.SetMaskButton = uibutton(app.VideoSelectionTab, 'push');
            app.SetMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SetMaskButtonPushed, true);
            app.SetMaskButton.Enable = 'off';
            app.SetMaskButton.Text = 'Set Mask';
            app.VideoTitleTextAreaLabel = uilabel(app.VideoSelectionTab);
            app.VideoTitleTextAreaLabel.Enable = 'off';
            app.VideoTitleTextAreaLabel.Text = 'Video Title';
            app.VideoTitleTextArea = uitextarea(app.VideoSelectionTab);
            app.VideoTitleTextArea.Enable = 'off';
            app.UIAxes = uiaxes(app.VideoSelectionTab);
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.AddDeletePointsButton = uibutton(app.ProcessingTab, 'push');
            app.AddDeletePointsButton.ButtonPushedFcn = createCallbackFcn(app, @AddDeletePointsButtonPushed, true);
            app.AddDeletePointsButton.Enable = 'off';
            app.AddDeletePointsButton.Text = 'Add/Delete Points';
            app.ApplyRectangularGridButton = uibutton(app.ProcessingTab, 'push');
            app.ApplyRectangularGridButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyRectangularGridButtonPushed, true);
            app.ApplyRectangularGridButton.Enable = 'off';
            app.ApplyRectangularGridButton.Text = 'Apply Rectangular Grid';
            app.ApplyPolarGridButton = uibutton(app.ProcessingTab, 'push');
            app.ApplyPolarGridButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPolarGridButtonPushed, true);
            app.ApplyPolarGridButton.Enable = 'off';
            app.ApplyPolarGridButton.Text = 'Apply Polar Grid';
            app.SampleRegionWidthLabel = uilabel(app.ProcessingTab);
            app.SampleRegionWidthLabel.Text = {'Sample Region'; 'Width'};
            app.SampleRegionWidthSpinner = uispinner(app.ProcessingTab);
            app.SampleRegionWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleRegionWidthSpinnerValueChanged, true);
            app.SampleRegionWidthSpinner.Enable = 'off';
            app.HorizontalPixelSpacingSpinnerLabel = uilabel(app.ProcessingTab);
            app.HorizontalPixelSpacingSpinnerLabel.Text = {'Horizontal Pixel'; 'Spacing'};
            app.HorizontalPixelSpacingSpinner = uispinner(app.ProcessingTab);
            app.HorizontalPixelSpacingSpinner.ValueChangedFcn = createCallbackFcn(app, @HorizontalPixelSpacingSpinnerValueChanged, true);
            app.HorizontalPixelSpacingSpinner.Enable = 'off';
            app.VerticalPixelSpacingSpinnerLabel = uilabel(app.ProcessingTab);
            app.VerticalPixelSpacingSpinnerLabel.Text = {'Vertical Pixel'; 'Spacing'};
            app.VerticalPixelSpacingSpinner = uispinner(app.ProcessingTab);
            app.VerticalPixelSpacingSpinner.ValueChangedFcn = createCallbackFcn(app, @VerticalPixelSpacingSpinnerValueChanged, true);
            app.VerticalPixelSpacingSpinner.Enable = 'off';
            app.HorizontalSamplesSpinnerLabel = uilabel(app.ProcessingTab);
            app.HorizontalSamplesSpinnerLabel.Text = {'Horizontal'; 'Samples'};
            app.HorizontalSamplesSpinner = uispinner(app.ProcessingTab);
            app.HorizontalSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @HorizontalSamplesSpinnerValueChanged, true);
            app.HorizontalSamplesSpinner.Enable = 'off';
            app.VerticalSamplesSpinnerLabel = uilabel(app.ProcessingTab);
            app.VerticalSamplesSpinnerLabel.Text = {'Vertical'; 'Samples'};
            app.VerticalSamplesSpinner = uispinner(app.ProcessingTab);
            app.VerticalSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @VerticalSamplesSpinnerValueChanged, true);
            app.VerticalSamplesSpinner.Enable = 'off';
            app.RadialPixelSpacingSpinnerLabel = uilabel(app.ProcessingTab);
            app.RadialPixelSpacingSpinnerLabel.Text = {'Radial Pixel'; 'Spacing'};
            app.RadialPixelSpacingSpinner = uispinner(app.ProcessingTab);
            app.RadialPixelSpacingSpinner.ValueChangedFcn = createCallbackFcn(app, @RadialPixelSpacingSpinnerValueChanged, true);
            app.RadialPixelSpacingSpinner.Enable = 'off';
            app.StartAngleSpinnerLabel = uilabel(app.ProcessingTab);
            app.StartAngleSpinnerLabel.Text = 'Start Angle';
            app.StartAngleSpinner = uispinner(app.ProcessingTab);
            app.StartAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @StartAngleSpinnerValueChanged, true);
            app.StartAngleSpinner.Enable = 'off';
            app.RadialSamplesSpinnerLabel = uilabel(app.ProcessingTab);
            app.RadialSamplesSpinnerLabel.Text = {'Radial'; 'Samples'};
            app.RadialSamplesSpinner = uispinner(app.ProcessingTab);
            app.RadialSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @RadialSamplesSpinnerValueChanged, true);
            app.RadialSamplesSpinner.Enable = 'off';
            app.AngularSamplesSpinnerLabel = uilabel(app.ProcessingTab);
            app.AngularSamplesSpinnerLabel.Text = {'Angular'; 'Samples'};
            app.AngularSamplesSpinner = uispinner(app.ProcessingTab);
            app.AngularSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @AngularSamplesSpinnerValueChanged, true);
            app.AngularSamplesSpinner.Enable = 'off';
            app.ClearAllButton = uibutton(app.ProcessingTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Enable = 'off';
            app.ClearAllButton.Text = 'Clear All';
            app.UseEntireImageCheckBox = uicheckbox(app.ProcessingTab);
            app.UseEntireImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseEntireImageCheckBoxValueChanged, true);
            app.UseEntireImageCheckBox.Enable = 'off';
            app.UseEntireImageCheckBox.Text = 'Use Entire Image';
            app.NumberofprocessingscalesSpinnerLabel = uilabel(app.ProcessingTab);
            app.NumberofprocessingscalesSpinnerLabel.Text = {'Number of'; 'processing scales'};
            app.NumberofprocessingscalesSpinner = uispinner(app.ProcessingTab);
            app.NumberofprocessingscalesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofprocessingscalesSpinnerValueChanged, true);
            app.NumberofprocessingscalesSpinner.Enable = 'off';
            app.BasescaleEditFieldLabel = uilabel(app.ProcessingTab);
            app.BasescaleEditFieldLabel.Text = 'Base scale';
            app.BasescaleEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.BasescaleEditField.ValueChangedFcn = createCallbackFcn(app, @BasescaleEditFieldValueChanged, true);
            app.BasescaleEditField.Enable = 'off';
            app.UseMaxScalesButton = uibutton(app.ProcessingTab, 'push');
            app.UseMaxScalesButton.ButtonPushedFcn = createCallbackFcn(app, @UseMaxScalesButtonPushed, true);
            app.UseMaxScalesButton.Enable = 'off';
            app.UseMaxScalesButton.Text = 'Use Max Scales';
            app.ProcessVideoButton = uibutton(app.ProcessingTab, 'push');
            app.ProcessVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessVideoButtonPushed, true);
            app.ProcessVideoButton.Enable = 'off';
            app.ProcessVideoButton.Text = 'Process Video';
            app.ProcessingProgressGaugeLabel = uilabel(app.ProcessingTab);
            app.ProcessingProgressGaugeLabel.Enable = 'off';
            app.ProcessingProgressGaugeLabel.Text = 'Processing Progress';
            app.ProcessingProgressGauge = uigauge(app.ProcessingTab, 'semicircular');
            app.ProcessingProgressGauge.Enable = 'off';
            app.UIAxes2 = uiaxes(app.ProcessingTab);
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post-Processing';
            app.ClearBoxesButton = uibutton(app.PostProcessingTab, 'push');
            app.ClearBoxesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBoxesButtonPushed, true);
            app.ClearBoxesButton.Enable = 'off';
            app.ClearBoxesButton.Text = 'Clear Boxes';
            app.UseAllBoxesButton = uibutton(app.PostProcessingTab, 'push');
            app.UseAllBoxesButton.ButtonPushedFcn = createCallbackFcn(app, @UseAllBoxesButtonPushed, true);
            app.UseAllBoxesButton.Enable = 'off';
            app.UseAllBoxesButton.Text = 'Use All Boxes';
            app.SelectBoxesButton = uibutton(app.PostProcessingTab, 'push');
            app.SelectBoxesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBoxesButtonPushed, true);
            app.SelectBoxesButton.Enable = 'off';
            app.SelectBoxesButton.Text = 'Select Boxes';
            app.ShowAverageVelocitiesButton = uibutton(app.PostProcessingTab, 'state');
            app.ShowAverageVelocitiesButton.Enable = 'off';
            app.ShowAverageVelocitiesButton.Text = 'Show Average Velocities';
            app.ShowAveragePositionsButton = uibutton(app.PostProcessingTab, 'state');
            app.ShowAveragePositionsButton.Enable = 'off';
            app.ShowAveragePositionsButton.Text = 'Show Average Positions';
            app.ShowonlyselectedboxesCheckBox = uicheckbox(app.PostProcessingTab);
            app.ShowonlyselectedboxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowonlyselectedboxesCheckBoxValueChanged, true);
            app.ShowonlyselectedboxesCheckBox.Enable = 'off';
            app.ShowonlyselectedboxesCheckBox.Text = 'Show only selected boxes';
            app.GeneratePlotsButton = uibutton(app.PostProcessingTab, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Enable = 'off';
            app.GeneratePlotsButton.Text = 'Generate Plots';
            app.SaveProcessedDataButton = uibutton(app.PostProcessingTab, 'push');
            app.SaveProcessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedDataButtonPushed, true);
            app.SaveProcessedDataButton.Enable = 'off';
            app.SaveProcessedDataButton.Text = 'Save Processed Data';
            app.ShowVelocityFrequenciesButton = uibutton(app.PostProcessingTab, 'state');
            app.ShowVelocityFrequenciesButton.Enable = 'off';
            app.ShowVelocityFrequenciesButton.Text = 'Show Velocity Frequencies';
            app.ShowPositionFrequenciesButton = uibutton(app.PostProcessingTab, 'state');
            app.ShowPositionFrequenciesButton.Enable = 'off';
            app.ShowPositionFrequenciesButton.Text = 'Show Position Frequencies';
            app.SGWindowEditFieldLabel = uilabel(app.PostProcessingTab);
            app.SGWindowEditFieldLabel.Enable = 'off';
            app.SGWindowEditFieldLabel.Text = 'SG Window';
            app.SGWindowEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.SGWindowEditField.Enable = 'off';
            app.ShowSpectrogramsButton = uibutton(app.PostProcessingTab, 'state');
            app.ShowSpectrogramsButton.Enable = 'off';
            app.ShowSpectrogramsButton.Text = 'Show Spectrograms';
            app.SGVBucketsEditFieldLabel = uilabel(app.PostProcessingTab);
            app.SGVBucketsEditFieldLabel.Enable = 'off';
            app.SGVBucketsEditFieldLabel.Text = 'SG V. Buckets';
            app.SGVBucketsEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.SGVBucketsEditField.Enable = 'off';
            app.LoadProcessedDataButton = uibutton(app.PostProcessingTab, 'push');
            app.LoadProcessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProcessedDataButtonPushed, true);
            app.LoadProcessedDataButton.Text = 'Load Processed Data';
            app.FrameRateEditFieldLabel = uilabel(app.PostProcessingTab);
            app.FrameRateEditFieldLabel.Enable = 'off';
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.FrameRateEditField.Enable = 'off';
            app.HighpassFilterEditFieldLabel = uilabel(app.PostProcessingTab);
            app.HighpassFilterEditFieldLabel.Enable = 'off';
            app.HighpassFilterEditFieldLabel.Text = 'Highpass Filter';
            app.HighpassFilterEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.HighpassFilterEditField.ValueChangedFcn = createCallbackFcn(app, @HighpassFilterEditFieldValueChanged, true);
            app.HighpassFilterEditField.Enable = 'off';
            app.LowpassFilterEditFieldLabel = uilabel(app.PostProcessingTab);
            app.LowpassFilterEditFieldLabel.Enable = 'off';
            app.LowpassFilterEditFieldLabel.Text = 'Lowpass Filter';
            app.LowpassFilterEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.LowpassFilterEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassFilterEditFieldValueChanged, true);
            app.LowpassFilterEditField.Enable = 'off';
            app.UIAxes3 = uiaxes(app.PostProcessingTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoVibrometry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end